classdef PROYECTONORMA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SeleccioneelpuertoDropDown  matlab.ui.control.DropDown
        SeleccioneelpuertoDropDownLabel  matlab.ui.control.Label
        InicioButton                matlab.ui.control.Button
        Impedancia                  matlab.ui.control.NumericEditField
        ImpedanciaLabel             matlab.ui.control.Label
        CorrienteRMS                matlab.ui.control.NumericEditField
        CorrienteRMSLabel           matlab.ui.control.Label
        VoutRMS                     matlab.ui.control.NumericEditField
        VoutRMSLabel                matlab.ui.control.Label
        VinRMS                      matlab.ui.control.NumericEditField
        VinRMSLabel                 matlab.ui.control.Label
        FrancoVzquezTayriMichellePonceMoralesIsaacRuvalcabaMedinaMarianaLabel  matlab.ui.control.Label
        RESULTADOSLabel             matlab.ui.control.Label
        Image_2                     matlab.ui.control.Image
        Image                       matlab.ui.control.Image
        titulo                      matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SeleccioneelpuertoDropDown.Items = serialportlist("available");
        end

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)
            puerto = app.SeleccioneelpuertoDropDown.Value;      % <-- Cambia según tu PC
            disp(puerto);
            baud   = 115200;
            esp = serialport(puerto, baud);
            flush(esp);
            pause(1);
            linea = "";
            while ~contains(linea, "START")
                linea = readline(esp);
            end
            samples = 8000;     % debe coincidir con Arduino
            fs = 20000;        % 20 kHz, igual que en Arduino
            
            v_in  = zeros(1, samples);
            v_out = zeros(1, samples);
            
            %% === LEER BLOQUE DE DATOS ===
            for k = 1:samples
                linea = readline(esp);
                datos = sscanf(linea, "%f,%f,%f");
            
                if numel(datos) == 3
                    v_in(k)  = (datos(2) * 3.3) / 4095;          % Vin enviado por el ESP32
                    v_out(k) = (datos(3) * 3.3) / 4095;   % ADC crudo → Volts
                end
            end
            
            linea = "";
            while ~contains(linea, "END")
                linea = readline(esp);
            end

            v_in = v_in(6000:end);
            v_out = v_out(6000:end);

            v_in = detrend(v_in);
           
            v_out = detrend(v_out);
           
            
            Vin_rms  = rms(v_in);
            Vout_rms = rms(v_out);
            
            %I_rms = Vin_rms / Rlimit;
            I_rms = 0.00003536;
            Z = Vout_rms / I_rms;
            
            a = 1.1152; %1.1170623895569638;
            b = 36.4719; %36.85084032461811;
            Z_corr = a * Z + b;


            app.CorrienteRMS.Value = I_rms*1000000;
            app.VinRMS.Value = Vin_rms;
            app.VoutRMS.Value = Vout_rms;
            app.Impedancia.Value = Z_corr;

            t = (0:2000)/fs;
            
            %plot(timeVectorEEG, data, 'b', 'Parent', app.UIsignal_2);
            plot(t,v_in,'b','Parent',app.UIAxes);
            plot(t,v_out,'b','Parent',app.UIAxes2);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Señal de Entrada')
            xlabel(app.UIAxes, 'Segundos')
            ylabel(app.UIAxes, 'Voltaje')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Señal de Salida')
            xlabel(app.UIAxes2, 'Segundos')
            ylabel(app.UIAxes2, 'Voltaje')
            zlabel(app.UIAxes2, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.titulo = uilabel(app.UIFigure);
            app.titulo.Text = 'Dispositivo de Bioimpedancia para la Detección de Glaucoma';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'cucei-logo.png');
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, '500px-Escudo_UdeG.svg.png');
            app.RESULTADOSLabel = uilabel(app.UIFigure);
            app.RESULTADOSLabel.Text = 'RESULTADOS';
            app.FrancoVzquezTayriMichellePonceMoralesIsaacRuvalcabaMedinaMarianaLabel = uilabel(app.UIFigure);
            app.FrancoVzquezTayriMichellePonceMoralesIsaacRuvalcabaMedinaMarianaLabel.Text = 'Franco Vázquez Tayri Michelle | Ponce Morales Isaac | Ruvalcaba Medina Mariana';
            app.VinRMSLabel = uilabel(app.UIFigure);
            app.VinRMSLabel.Text = 'Vin RMS:';
            app.VinRMS = uieditfield(app.UIFigure, 'numeric');
            app.VoutRMSLabel = uilabel(app.UIFigure);
            app.VoutRMSLabel.Text = 'Vout RMS:';
            app.VoutRMS = uieditfield(app.UIFigure, 'numeric');
            app.CorrienteRMSLabel = uilabel(app.UIFigure);
            app.CorrienteRMSLabel.Text = {'Corriente '; 'RMS (uA):'};
            app.CorrienteRMS = uieditfield(app.UIFigure, 'numeric');
            app.ImpedanciaLabel = uilabel(app.UIFigure);
            app.ImpedanciaLabel.Text = 'Impedancia:';
            app.Impedancia = uieditfield(app.UIFigure, 'numeric');
            app.InicioButton = uibutton(app.UIFigure, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Text = 'Inicio';
            app.SeleccioneelpuertoDropDownLabel = uilabel(app.UIFigure);
            app.SeleccioneelpuertoDropDownLabel.Text = 'Seleccione el puerto:';
            app.SeleccioneelpuertoDropDown = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PROYECTONORMA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end